classdef change_detection_demonstration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        ChangeDetectionPanel            matlab.ui.container.Panel
        DoneLampCD                      matlab.ui.control.Lamp
        DoneLampLabel_5                 matlab.ui.control.Label
        RunButtonChangeDetection        matlab.ui.control.Button
        NormalsEditFieldCD              matlab.ui.control.NumericEditField
        Route2actualLabel_3             matlab.ui.control.Label
        GridSizeEditFieldCD             matlab.ui.control.NumericEditField
        Route2actualLabel_2             matlab.ui.control.Label
        Route2ActualEditFieldCD         matlab.ui.control.EditField
        Route2currentLabel_2            matlab.ui.control.Label
        Route1OriginalEditFieldCD       matlab.ui.control.EditField
        Route1originalEditFieldLabel_4  matlab.ui.control.Label
        PointCloudRegistrationPanel     matlab.ui.container.Panel
        DoneLampRegistration            matlab.ui.control.Lamp
        DoneLampLabel_4                 matlab.ui.control.Label
        SaveCheckBoxRegistration        matlab.ui.control.CheckBox
        RunButtonRegistration           matlab.ui.control.Button
        Route2ActualEditFieldRegistration  matlab.ui.control.EditField
        Route2currentLabel              matlab.ui.control.Label
        Route1OriginalEditFieldRegistration  matlab.ui.control.EditField
        Route1originalEditFieldLabel_3  matlab.ui.control.Label
        PointCloudDenoisingPanel        matlab.ui.container.Panel
        DoneLampDenoise                 matlab.ui.control.Lamp
        DoneLampLabel_3                 matlab.ui.control.Label
        SaveCheckBoxDenoise             matlab.ui.control.CheckBox
        RunButtonDenoise                matlab.ui.control.Button
        NumberOfNeigbhoursEditFieldDenoise  matlab.ui.control.NumericEditField
        RouteLabel_4                    matlab.ui.control.Label
        DistanceThresholdEditFieldDenoise  matlab.ui.control.NumericEditField
        RouteLabel_3                    matlab.ui.control.Label
        RouteEditFieldDenoise           matlab.ui.control.EditField
        RouteEditFieldLabel_3           matlab.ui.control.Label
        StructurePanel                  matlab.ui.container.Panel
        DoneLampStructure               matlab.ui.control.Lamp
        DoneLampLabel_2                 matlab.ui.control.Label
        SaveCheckBoxStructure           matlab.ui.control.CheckBox
        RunButtonStructure              matlab.ui.control.Button
        RouteEditFieldStructure         matlab.ui.control.EditField
        RouteEditFieldLabel_2           matlab.ui.control.Label
        CameramotionPanel               matlab.ui.container.Panel
        DoneLampOdometry                matlab.ui.control.Lamp
        DoneLampLabel                   matlab.ui.control.Label
        SaveCheckBoxOdometry            matlab.ui.control.CheckBox
        RunButtonOdometry               matlab.ui.control.Button
        MinimalParallaxEditField        matlab.ui.control.NumericEditField
        MinimalparallaxEditFieldLabel   matlab.ui.control.Label
        SURFthresholdEditField          matlab.ui.control.NumericEditField
        SURFthresholdEditFieldLabel     matlab.ui.control.Label
        RouteEditFieldOdometry          matlab.ui.control.EditField
        RouteEditFieldLabel             matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = ["95 (original) and 99_1 (current)", 50, 200, 0.25, 44;
                "100 (original) and 101 (current)", 50, 1200, 1, 39;
                "102 (original) and 103 (current)", 50, "1400 (102), 200 (103)", 1, 40;
                "104 (original) and 105 (current)", 50, 300, 2, 47;
                "106 (original) and 107 (current)", 30, 400, 2, 81;
                "109 (original) and 110 (current)", 30, "300 (109), 600 (110)", 1, 83;
                "111 (original) and 112 (current)", 50, "300 (111), 400 (112)", 1, 42];
        end

        % Button pushed function: RunButtonOdometry
        function RunButtonOdometryPushed(app, event)
            app.DoneLampOdometry.Color = [1 1 1];
            clearvars -except app event
            filepath = fileparts(mfilename('fullpath'));
            cd (filepath)
            addpath modules\sfm_odometry
            [vSet,keyFramesIdxVec] = sfmOdometry(app.RouteEditFieldOdometry.Value, app.SURFthresholdEditField.Value, 1);
            
            if app.SaveCheckBoxOdometry.Value == 1
                addpath modules\sfm_odometry\functions
                saveOdometry(app.RouteEditFieldOdometry.Value, vSet, keyFramesIdxVec);
            end
            app.DoneLampOdometry.Color = "g";
        end

        % Button pushed function: RunButtonStructure
        function RunButtonStructurePushed(app, event)
            app.DoneLampStructure.Color = [1 1 1];
            clearvars -except app event
            filepath = fileparts(mfilename('fullpath'));
            cd (filepath)
            addpath modules\sfm_structure
            [ptCloud,vSet,wpSet] = sfmStructure(app.RouteEditFieldStructure.Value);

            if (app.SaveCheckBoxStructure.Value == 1)
                addpath modules\sfm_structure\functions
                saveStructure(app.RouteEditFieldStructure.Value,ptCloud,vSet,wpSet);
            end

            app.DoneLampStructure.Color = "g";
        end

        % Button pushed function: RunButtonDenoise
        function RunButtonDenoisePushed(app, event)
            app.DoneLampDenoise.Color = [1 1 1];
            clearvars -except app event 
            filepath = fileparts(mfilename('fullpath'));
            cd (filepath)
            addpath modules\pc_denoise
            [ptCloudDenoised,wpSetDenoised] = pcDenoise(app.RouteEditFieldDenoise.Value, ...
                1e-5, app.NumberOfNeigbhoursEditFieldDenoise.Value);

            if (app.SaveCheckBoxDenoise.Value == 1)
                addpath modules\pc_denoise\functions
                savePCDenoise(app.RouteEditFieldDenoise.Value,ptCloudDenoised,wpSetDenoised);
            end

            app.DoneLampDenoise.Color = "g";
        end

        % Button pushed function: RunButtonRegistration
        function RunButtonRegistrationPushed(app, event)
            app.DoneLampRegistration.Color = [1 1 1];
            clearvars -except app event;
            filepath = fileparts(mfilename('fullpath'));
            cd (filepath)
            addpath modules\pc_registration
            tformSVD = pcRegistration(app.Route1OriginalEditFieldRegistration.Value, ...
                app.Route2ActualEditFieldRegistration.Value);

            if (app.SaveCheckBoxRegistration.Value == 1)
                addpath modules\pc_registration\functions
                savePCRegistration(app.Route1OriginalEditFieldRegistration.Value, ...
                    app.Route2ActualEditFieldRegistration.Value,tformSVD);
            end

            app.DoneLampRegistration.Color = "g";
        end

        % Button pushed function: RunButtonChangeDetection
        function RunButtonChangeDetectionPushed(app, event)
            app.DoneLampCD.Color = [1 1 1];
            clearvars -except app event;
            filepath = fileparts(mfilename('fullpath'));
            cd (filepath)
            addpath modules\change_detection
            changeDetection(app.Route1OriginalEditFieldCD.Value,app.Route2ActualEditFieldCD.Value, ...
                app.GridSizeEditFieldCD.Value,app.NormalsEditFieldCD.Value);

            app.DoneLampCD.Color = "g";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CameramotionPanel = uipanel(app.UIFigure);
            app.CameramotionPanel.Title = '1) Camera motion';
            app.RouteEditFieldLabel = uilabel(app.CameramotionPanel);
            app.RouteEditFieldLabel.Text = 'Route';
            app.RouteEditFieldOdometry = uieditfield(app.CameramotionPanel, 'text');
            app.SURFthresholdEditFieldLabel = uilabel(app.CameramotionPanel);
            app.SURFthresholdEditFieldLabel.Text = 'SURF threshold';
            app.SURFthresholdEditField = uieditfield(app.CameramotionPanel, 'numeric');
            app.MinimalparallaxEditFieldLabel = uilabel(app.CameramotionPanel);
            app.MinimalparallaxEditFieldLabel.Enable = 'off';
            app.MinimalparallaxEditFieldLabel.Visible = 'off';
            app.MinimalparallaxEditFieldLabel.Text = 'Minimal parallax';
            app.MinimalParallaxEditField = uieditfield(app.CameramotionPanel, 'numeric');
            app.MinimalParallaxEditField.Editable = 'off';
            app.MinimalParallaxEditField.Enable = 'off';
            app.MinimalParallaxEditField.Visible = 'off';
            app.RunButtonOdometry = uibutton(app.CameramotionPanel, 'push');
            app.RunButtonOdometry.ButtonPushedFcn = createCallbackFcn(app, @RunButtonOdometryPushed, true);
            app.RunButtonOdometry.Text = 'Run';
            app.SaveCheckBoxOdometry = uicheckbox(app.CameramotionPanel);
            app.SaveCheckBoxOdometry.Enable = 'off';
            app.SaveCheckBoxOdometry.Visible = 'off';
            app.SaveCheckBoxOdometry.Text = 'Save';
            app.DoneLampLabel = uilabel(app.CameramotionPanel);
            app.DoneLampLabel.Text = 'Done';
            app.DoneLampOdometry = uilamp(app.CameramotionPanel);
            app.StructurePanel = uipanel(app.UIFigure);
            app.StructurePanel.Title = '2) 3D structure';
            app.RouteEditFieldLabel_2 = uilabel(app.StructurePanel);
            app.RouteEditFieldLabel_2.Text = 'Route';
            app.RouteEditFieldStructure = uieditfield(app.StructurePanel, 'text');
            app.RunButtonStructure = uibutton(app.StructurePanel, 'push');
            app.RunButtonStructure.ButtonPushedFcn = createCallbackFcn(app, @RunButtonStructurePushed, true);
            app.RunButtonStructure.Text = 'Run';
            app.SaveCheckBoxStructure = uicheckbox(app.StructurePanel);
            app.SaveCheckBoxStructure.Enable = 'off';
            app.SaveCheckBoxStructure.Visible = 'off';
            app.SaveCheckBoxStructure.Text = 'Save';
            app.DoneLampLabel_2 = uilabel(app.StructurePanel);
            app.DoneLampLabel_2.Text = 'Done';
            app.DoneLampStructure = uilamp(app.StructurePanel);
            app.PointCloudDenoisingPanel = uipanel(app.UIFigure);
            app.PointCloudDenoisingPanel.Title = '3) Point cloud denoising';
            app.RouteEditFieldLabel_3 = uilabel(app.PointCloudDenoisingPanel);
            app.RouteEditFieldLabel_3.Text = 'Route';
            app.RouteEditFieldDenoise = uieditfield(app.PointCloudDenoisingPanel, 'text');
            app.RouteLabel_3 = uilabel(app.PointCloudDenoisingPanel);
            app.RouteLabel_3.Enable = 'off';
            app.RouteLabel_3.Visible = 'off';
            app.RouteLabel_3.Text = 'Distance threshold';
            app.DistanceThresholdEditFieldDenoise = uieditfield(app.PointCloudDenoisingPanel, 'numeric');
            app.DistanceThresholdEditFieldDenoise.Editable = 'off';
            app.DistanceThresholdEditFieldDenoise.Enable = 'off';
            app.DistanceThresholdEditFieldDenoise.Visible = 'off';
            app.RouteLabel_4 = uilabel(app.PointCloudDenoisingPanel);
            app.RouteLabel_4.Text = 'Number of neighbours';
            app.NumberOfNeigbhoursEditFieldDenoise = uieditfield(app.PointCloudDenoisingPanel, 'numeric');
            app.RunButtonDenoise = uibutton(app.PointCloudDenoisingPanel, 'push');
            app.RunButtonDenoise.ButtonPushedFcn = createCallbackFcn(app, @RunButtonDenoisePushed, true);
            app.RunButtonDenoise.Text = 'Run';
            app.SaveCheckBoxDenoise = uicheckbox(app.PointCloudDenoisingPanel);
            app.SaveCheckBoxDenoise.Enable = 'off';
            app.SaveCheckBoxDenoise.Visible = 'off';
            app.SaveCheckBoxDenoise.Text = 'Save';
            app.DoneLampLabel_3 = uilabel(app.PointCloudDenoisingPanel);
            app.DoneLampLabel_3.Text = 'Done';
            app.DoneLampDenoise = uilamp(app.PointCloudDenoisingPanel);
            app.PointCloudRegistrationPanel = uipanel(app.UIFigure);
            app.PointCloudRegistrationPanel.Title = '4) Point cloud registration';
            app.Route1originalEditFieldLabel_3 = uilabel(app.PointCloudRegistrationPanel);
            app.Route1originalEditFieldLabel_3.Text = 'Route 1 (original)';
            app.Route1OriginalEditFieldRegistration = uieditfield(app.PointCloudRegistrationPanel, 'text');
            app.Route2currentLabel = uilabel(app.PointCloudRegistrationPanel);
            app.Route2currentLabel.Text = 'Route 2 (current)';
            app.Route2ActualEditFieldRegistration = uieditfield(app.PointCloudRegistrationPanel, 'text');
            app.RunButtonRegistration = uibutton(app.PointCloudRegistrationPanel, 'push');
            app.RunButtonRegistration.ButtonPushedFcn = createCallbackFcn(app, @RunButtonRegistrationPushed, true);
            app.RunButtonRegistration.Text = 'Run';
            app.SaveCheckBoxRegistration = uicheckbox(app.PointCloudRegistrationPanel);
            app.SaveCheckBoxRegistration.Enable = 'off';
            app.SaveCheckBoxRegistration.Visible = 'off';
            app.SaveCheckBoxRegistration.Text = 'Save';
            app.DoneLampLabel_4 = uilabel(app.PointCloudRegistrationPanel);
            app.DoneLampLabel_4.Text = 'Done';
            app.DoneLampRegistration = uilamp(app.PointCloudRegistrationPanel);
            app.ChangeDetectionPanel = uipanel(app.UIFigure);
            app.ChangeDetectionPanel.Title = '5) Change detection';
            app.Route1originalEditFieldLabel_4 = uilabel(app.ChangeDetectionPanel);
            app.Route1originalEditFieldLabel_4.Text = 'Route 1 (original)';
            app.Route1OriginalEditFieldCD = uieditfield(app.ChangeDetectionPanel, 'text');
            app.Route2currentLabel_2 = uilabel(app.ChangeDetectionPanel);
            app.Route2currentLabel_2.Text = 'Route 2 (current)';
            app.Route2ActualEditFieldCD = uieditfield(app.ChangeDetectionPanel, 'text');
            app.Route2actualLabel_2 = uilabel(app.ChangeDetectionPanel);
            app.Route2actualLabel_2.Text = 'Grid size';
            app.GridSizeEditFieldCD = uieditfield(app.ChangeDetectionPanel, 'numeric');
            app.Route2actualLabel_3 = uilabel(app.ChangeDetectionPanel);
            app.Route2actualLabel_3.Text = 'Neighborhood size (normals)';
            app.NormalsEditFieldCD = uieditfield(app.ChangeDetectionPanel, 'numeric');
            app.RunButtonChangeDetection = uibutton(app.ChangeDetectionPanel, 'push');
            app.RunButtonChangeDetection.ButtonPushedFcn = createCallbackFcn(app, @RunButtonChangeDetectionPushed, true);
            app.RunButtonChangeDetection.Text = 'Run';
            app.DoneLampLabel_5 = uilabel(app.ChangeDetectionPanel);
            app.DoneLampLabel_5.Text = 'Done';
            app.DoneLampCD = uilamp(app.ChangeDetectionPanel);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Route'; 'SURF threshold'; 'Number of neighbors'; 'Grid size'; 'Neighborhood size (normals)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = change_detection_demonstration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end